classdef IAN_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataSplitTab                  matlab.ui.container.Tab
        ValRatioSpinnerLabel          matlab.ui.control.Label
        ValRatioSpinner               matlab.ui.control.Spinner
        inputfirstLabel               matlab.ui.control.Label
        DataSplitButton               matlab.ui.control.Button
        TestRatioSpinnerLabel         matlab.ui.control.Label
        TestRatioSpinner              matlab.ui.control.Spinner
        OptimizationDropDownLabel     matlab.ui.control.Label
        OptimizationDropDown          matlab.ui.control.DropDown
        GeneralizationDropDownLabel   matlab.ui.control.Label
        GeneralizationDropDown        matlab.ui.control.DropDown
        TargetButton                  matlab.ui.control.Button
        DataImportLabel               matlab.ui.control.Label
        InputButton                   matlab.ui.control.Button
        TrainingTab                   matlab.ui.container.Tab
        HistoryDataExportPanel        matlab.ui.container.Panel
        alphabetaCheckBox             matlab.ui.control.CheckBox
        muCheckBox                    matlab.ui.control.CheckBox
        StopButton                    matlab.ui.control.Button
        TrainingButton                matlab.ui.control.Button
        ofLayerLabel                  matlab.ui.control.Label
        ofLayerSpinner                matlab.ui.control.Spinner
        PatienceSpinnerLabel          matlab.ui.control.Label
        PatienceSpinner               matlab.ui.control.Spinner
        MaxEpochSpinnerLabel          matlab.ui.control.Label
        MaxEpochSpinner               matlab.ui.control.Spinner
        RepetitionSpinnerLabel        matlab.ui.control.Label
        RepetitionSpinner             matlab.ui.control.Spinner
        UITable                       matlab.ui.control.Table
        IANLabel                      matlab.ui.control.Label
        InhaASCENTNeuralnetworkLabel  matlab.ui.control.Label
        beta14Label                   matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function updateTableFcn(app)
             M = app.ofLayerSpinner.Value;
            celldata = {10;'Tanh'}';
            for i=2:M
                celldata{i,1} = 10;
                celldata{i,2} = {'Tanh'};
            end
            
            celldata{M,1} = NaN; % 마지막 layer의 neuron 값은 의미가 없으므로 0으로 설정
            
            t = cell2table(celldata);
            t.Properties.VariableNames = {'Neuron','Transfer'};
            cats = categorical(t.Transfer,{'Logsig','Tanh','Purelin','ReLU'});
            t.Transfer = cats;

            app.UITable.Data = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [fileinput,pathinput] = uigetfile('*.txt','Load file');
            fout = fopen('splitinfo.log', 'w');
            fprintf(fout, '%s%s\n', pathinput, fileinput);
            fclose(fout);
        end

        % Button pushed function: TargetButton
        function TargetButtonPushed(app, event)
            [filetarget,pathtarget] = uigetfile('*.txt','Load file');
            fout = fopen('splitinfo.log', 'a');
            fprintf(fout, '%s%s\n', pathtarget, filetarget);
            fclose(fout);
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)

            if app.GeneralizationDropDown.Value == "Early Stopping"
                generalization = 0;     % Early Stopping
            else
                generalization = 1;     % Regularization
            end
            
            repeat_no = app.RepetitionSpinner.Value;
            max_step = app.MaxEpochSpinner.Value;
            patience = app.PatienceSpinner.Value;
            M = app.ofLayerSpinner.Value;
            
            TransFnc = zeros(M,1);
            
            
            Neuron = app.UITable.DisplayData.Neuron;
            Trans = app.UITable.DisplayData.Transfer;      

%             Neuron = zeros(M-1,1);

            for i=1:M
                if Trans(i) == "Logsig"
                    TransFnc(i) = 1;
                elseif Trans(i) == "Tanh"
                    TransFnc(i) = 2;
                elseif Trans(i) == "Purelin"
                    TransFnc(i) = 3;
                else
                    TransFnc(i) = 4;
                end
            end
            
            % Parameter File outputlayer
            fout = fopen('info.log', 'w');
            fprintf(fout, '%d\n', generalization);
            fprintf(fout, '%d\n', repeat_no);
            fprintf(fout, '%d\n', max_step);
            
            if generalization == 0
                fprintf(fout, '%d\n', patience);
            else
                % fprintf(fout, '%f\n', lamda);
            end
            
            fprintf(fout, '%d\n', M);
            fprintf(fout, '%d\n', TransFnc);
            fprintf(fout, '%d\n', Neuron);
            
            fclose(fout);
            
            muout = 0;
            alphabetaout = 0;
            
            if app.muCheckBox.Value == true
                muout = 1;
            end
            
            if app.alphabetaCheckBox.Value == true
                alphabetaout = 1;
            end
            
            fout = fopen('hisout.txt','w');
            fprintf(fout, '%d\n', muout);
            if app.GeneralizationDropDown.Value == "Regularization"
                fprintf(fout, '%d\n', alphabetaout);
            end
            fclose(fout);
            
            % Run Training
            trainingCode
        end

        % Button pushed function: DataSplitButton
        function DataSplitButtonPushed(app, event)
            
            ValRatio  = app.ValRatioSpinner.Value;
            TestRatio = app.TestRatioSpinner.Value;
            
            % Parameter File outputlayer
            fout = fopen('splitinfo.log', 'a');
            
            fprintf(fout, '%f\n', ValRatio);
            fprintf(fout, '%f\n', TestRatio);
            
            fclose(fout);
            
%             startTableFcn(app);
            
            system('DataSplit_1.0.exe');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

        end

        % Value changed function: ValRatioSpinner
        function ValRatioSpinnerValueChanged(app, event)
            
        end

        % Drop down opening function: GeneralizationDropDown
        function GeneralizationDropDownOpening(app, event)

        end

        % Value changed function: GeneralizationDropDown
        function GeneralizationDropDownValueChanged(app, event)
            if app.GeneralizationDropDown.Value == "Regularization"
                app.ValRatioSpinnerLabel.Enable = 'off';
                app.ValRatioSpinner.Enable = 'off';
                app.ValRatioSpinner.Value = 0;
                
                app.PatienceSpinner.Enable = 'off';
                app.PatienceSpinnerLabel.Enable = 'off';
                
                app.alphabetaCheckBox.Enable = 'on';
            else
                app.ValRatioSpinnerLabel.Enable = 'on';
                app.ValRatioSpinner.Enable = 'on';
                
                app.PatienceSpinner.Enable = 'on';
                app.PatienceSpinnerLabel.Enable = 'on';
                
                app.alphabetaCheckBox.Enable = 'off';
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
         
        end

        % Value changed function: ofLayerSpinner
        function ofLayerSpinnerValueChanged(app, event)
            updateTableFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'inha logo.png';
            app.beta14Label = uilabel(app.UIFigure);
            app.beta14Label.Text = 'beta 1.4';
            app.InhaASCENTNeuralnetworkLabel = uilabel(app.UIFigure);
            app.InhaASCENTNeuralnetworkLabel.Text = 'Inha ASCENT Neural network';
            app.IANLabel = uilabel(app.UIFigure);
            app.IANLabel.Text = 'IAN';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataSplitTab = uitab(app.TabGroup);
            app.DataSplitTab.Title = 'Data Split';
            app.InputButton = uibutton(app.DataSplitTab, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.DataImportLabel = uilabel(app.DataSplitTab);
            app.DataImportLabel.Text = 'Data Import';
            app.TargetButton = uibutton(app.DataSplitTab, 'push');
            app.TargetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetButtonPushed, true);
            app.TargetButton.Text = 'Target';
            app.GeneralizationDropDown = uidropdown(app.DataSplitTab);
            app.GeneralizationDropDown.Items = {'Early Stopping', 'Regularization'};
            app.GeneralizationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GeneralizationDropDownOpening, true);
            app.GeneralizationDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralizationDropDownValueChanged, true);
            app.GeneralizationDropDown.Value = 'Early Stopping';
            app.GeneralizationDropDownLabel = uilabel(app.DataSplitTab);
            app.GeneralizationDropDownLabel.Text = 'Generalization';
            app.OptimizationDropDown = uidropdown(app.DataSplitTab);
            app.OptimizationDropDown.Items = {'LMBP'};
            app.OptimizationDropDown.Value = 'LMBP';
            app.OptimizationDropDownLabel = uilabel(app.DataSplitTab);
            app.OptimizationDropDownLabel.Text = 'Optimization';
            app.TestRatioSpinner = uispinner(app.DataSplitTab);
            app.TestRatioSpinner.Step = 0.01;
            app.TestRatioSpinner.Limits = [0 1];
            app.TestRatioSpinnerLabel = uilabel(app.DataSplitTab);
            app.TestRatioSpinnerLabel.Text = 'Test Ratio';
            app.DataSplitButton = uibutton(app.DataSplitTab, 'push');
            app.DataSplitButton.ButtonPushedFcn = createCallbackFcn(app, @DataSplitButtonPushed, true);
            app.DataSplitButton.Text = 'Data Split';
            app.inputfirstLabel = uilabel(app.DataSplitTab);
            app.inputfirstLabel.Text = '(input first)';
            app.ValRatioSpinner = uispinner(app.DataSplitTab);
            app.ValRatioSpinner.Step = 0.01;
            app.ValRatioSpinner.Limits = [0 1];
            app.ValRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @ValRatioSpinnerValueChanged, true);
            app.ValRatioSpinnerLabel = uilabel(app.DataSplitTab);
            app.ValRatioSpinnerLabel.Text = 'Val. Ratio';
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.UITable = uitable(app.TrainingTab);
            app.UITable.ColumnName = {'# of Neuron'; 'Transfer Fcn.'};
            app.UITable.RowName = {'Layer 1'; 'Layer 2'; 'Layer 3'; 'Layer 4'; 'Layer 5'; 'Layer 6'; 'Layer 7'; 'Layer 8'; 'Layer 9'; 'Layer 10'};
            app.UITable.ColumnSortable = [true true];
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RepetitionSpinner = uispinner(app.TrainingTab);
            app.RepetitionSpinner.Limits = [0 Inf];
            app.RepetitionSpinner.Value = 5;
            app.RepetitionSpinnerLabel = uilabel(app.TrainingTab);
            app.RepetitionSpinnerLabel.Text = 'Repetition';
            app.MaxEpochSpinner = uispinner(app.TrainingTab);
            app.MaxEpochSpinner.Limits = [1 Inf];
            app.MaxEpochSpinner.Value = 500;
            app.MaxEpochSpinnerLabel = uilabel(app.TrainingTab);
            app.MaxEpochSpinnerLabel.Text = 'Max. Epoch';
            app.PatienceSpinner = uispinner(app.TrainingTab);
            app.PatienceSpinner.Limits = [0 Inf];
            app.PatienceSpinner.Value = 20;
            app.PatienceSpinnerLabel = uilabel(app.TrainingTab);
            app.PatienceSpinnerLabel.Text = 'Patience';
            app.ofLayerSpinner = uispinner(app.TrainingTab);
            app.ofLayerSpinner.Limits = [1 10];
            app.ofLayerSpinner.ValueChangedFcn = createCallbackFcn(app, @ofLayerSpinnerValueChanged, true);
            app.ofLayerSpinner.Value = 2;
            app.ofLayerLabel = uilabel(app.TrainingTab);
            app.ofLayerLabel.Text = '# of Layer';
            app.TrainingButton = uibutton(app.TrainingTab, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.StopButton = uibutton(app.TrainingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HistoryDataExportPanel = uipanel(app.TrainingTab);
            app.HistoryDataExportPanel.Title = 'History Data Export';
            app.muCheckBox = uicheckbox(app.HistoryDataExportPanel);
            app.muCheckBox.Text = 'mu';
            app.muCheckBox.Value = true;
            app.alphabetaCheckBox = uicheckbox(app.HistoryDataExportPanel);
            app.alphabetaCheckBox.Enable = 'off';
            app.alphabetaCheckBox.Text = 'α, β';
            app.alphabetaCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAN_beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end